classdef withhead_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AveragePowerOutputEditField  matlab.ui.control.NumericEditField
        AveragePowerOutputEditFieldLabel  matlab.ui.control.Label
        CalculateAveragePowerOutputButton  matlab.ui.control.Button
        AverageFlowEditField         matlab.ui.control.NumericEditField
        AverageFlowEditFieldLabel    matlab.ui.control.Label
        EfficiencyEditField          matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel     matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        Q70flowEditField             matlab.ui.control.NumericEditField
        Q70flowEditFieldLabel        matlab.ui.control.Label
        Q10flowEditField             matlab.ui.control.NumericEditField
        Q10flowEditFieldLabel        matlab.ui.control.Label
        HeadEditField                matlab.ui.control.NumericEditField
        HeadEditFieldLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function HeadEditFieldValueChanged(app, event)
         
            
        end

        % Callback function
        function Q10flowEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function Q70flowEditFieldValueChanged(app, event)

            
        end

        % Callback function
        function RhoEditFieldValueChanged(app, event)
      
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Q10Flow = app.Q10flowEditField.Value;
            Q70Flow = app.Q70flowEditField.Value;
            nElements = 20;
            stepSize = (Q70Flow-Q10Flow)/(nElements-1);
            Efficiency = app.EfficiencyEditField.Value;
            Flow = Q10Flow:stepSize:Q70Flow;
            Rho = 1000;
            Head = app.HeadEditField.Value;
            g = 9.81;
            
            Power = 0.5*Rho*g*Efficiency*Head.*Flow/1000;
            y = Power;
            plot(app.UIAxes, y);
        end

        % Button pushed function: CalculateAveragePowerOutputButton
        function CalculateAveragePowerOutputButtonPushed(app, event)
            AverageFlow = app.AverageFlowEditField.Value;
            Rho = 1000;
            Efficiency = app.EfficiencyEditField.Value;
            g = 9.81;
            Head = app.HeadEditField.Value;
            AveragePower = 0.5*Rho*g*Efficiency*Head.*AverageFlow/1000;
            
            app.AveragePowerOutputEditField.Value = AveragePower;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Flow against Power Generation')
            xlabel(app.UIAxes, 'Flow (m3/s)')
            ylabel(app.UIAxes, 'Power Generated (kW)')
            zlabel(app.UIAxes, 'Z')
            app.HeadEditFieldLabel = uilabel(app.UIFigure);
            app.HeadEditFieldLabel.Text = 'Head';
            app.HeadEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeadEditField.Value = 0.183;
            app.Q10flowEditFieldLabel = uilabel(app.UIFigure);
            app.Q10flowEditFieldLabel.Text = 'Q10 flow';
            app.Q10flowEditField = uieditfield(app.UIFigure, 'numeric');
            app.Q10flowEditField.Value = 10;
            app.Q70flowEditFieldLabel = uilabel(app.UIFigure);
            app.Q70flowEditFieldLabel.Text = 'Q70 flow';
            app.Q70flowEditField = uieditfield(app.UIFigure, 'numeric');
            app.Q70flowEditField.Value = 85;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageFlowEditFieldLabel = uilabel(app.UIFigure);
            app.AverageFlowEditFieldLabel.Text = 'Average Flow';
            app.AverageFlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateAveragePowerOutputButton = uibutton(app.UIFigure, 'push');
            app.CalculateAveragePowerOutputButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAveragePowerOutputButtonPushed, true);
            app.CalculateAveragePowerOutputButton.Text = 'Calculate Average Power Output';
            app.AveragePowerOutputEditFieldLabel = uilabel(app.UIFigure);
            app.AveragePowerOutputEditFieldLabel.Text = 'Average Power Output';
            app.AveragePowerOutputEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = withhead_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end